classdef TradingSystem < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        Switch                    matlab.ui.control.Switch
        UITable                   matlab.ui.control.Table
        TickerEditFieldLabel      matlab.ui.control.Label
        TickerEditField           matlab.ui.control.EditField
        SecTypeEditFieldLabel     matlab.ui.control.Label
        SecTypeEditField          matlab.ui.control.EditField
        StrategyEditFieldLabel    matlab.ui.control.Label
        StrategyEditField         matlab.ui.control.EditField
        PriceTypeEditFieldLabel   matlab.ui.control.Label
        PriceTypeEditField        matlab.ui.control.EditField
        AddButton                 matlab.ui.control.Button
        UITable2                  matlab.ui.control.Table
        DeleteButton              matlab.ui.control.Button
        PauseButton               matlab.ui.control.Button
        StrategyDropDownLabel     matlab.ui.control.Label
        StrategyDropDown          matlab.ui.control.DropDown
        UpdateButton              matlab.ui.control.Button
        CashEditFieldLabel        matlab.ui.control.Label
        CashEditField             matlab.ui.control.NumericEditField
        PLEditFieldLabel          matlab.ui.control.Label
        PLEditField               matlab.ui.control.NumericEditField
        StartButton               matlab.ui.control.Button
        ExitButton                matlab.ui.control.Button
        PlotButton                matlab.ui.control.Button
        RealPLEditFieldLabel      matlab.ui.control.Label
        RealPLEditField           matlab.ui.control.NumericEditField
        AccountPositionInfoLabel  matlab.ui.control.Label
        OnRunSecuritiesLabel      matlab.ui.control.Label
        TickerEditField_2Label    matlab.ui.control.Label
        TickerEditField_2         matlab.ui.control.EditField
        TickerEditField_3Label    matlab.ui.control.Label
        TickerEditField_3         matlab.ui.control.EditField
        TickerEditField_4Label    matlab.ui.control.Label
        TickerEditField_4         matlab.ui.control.EditField
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        TSystem;    
        Timer; 
        RunningFlag;
        OnRun;
        Position;
        Cash;
        PL;
        RealPL;
    end
    
    
    methods (Access = private)
        
        % addObject
        function [] = addObject(app, ticker, sec, stra, price)
            flag = 0;
            if any(strcmp(ticker, fieldnames(app.Timer)))
                stop(app.Timer.(ticker));
                flag = 1;
            end

            app.TSystem.TradingObject.(ticker) = struct('symbol', ticker);
            app.TSystem.TradingObject.(ticker).SecType = sec;
            
            switch stra
                case 'MA'
                    Strategy.Name = 'MA';
                    Strategy.ShortWindow = 2;
                    Strategy.LongWindow = 3;
                case 'BollingerBand'
                    Strategy.Name = 'BollingerBand';
                    Strategy.n = 14;
                    Strategy.m = 2;
                case 'FastStochastic'
                    Strategy.Name = 'FastStochastic';
                    Strategy.Window = 14;
                case 'SlowStochastic'
                    Strategy.Name = 'SlowStochastic';
                    Strategy.Window = 14;
            end

            app.TSystem.TradingObject.(ticker).Strategy = Strategy;
            app.TSystem.TradingObject.(ticker).PriceType = price;
            
            app.TSystem.TradingObject.(ticker).LocalOrNot = 'localSymbol';
            app.TSystem.TradingObject.(ticker).Exchange = 'Globex';
            
            if strcmp(sec, 'FUT')
                app.TSystem.TradingObject.(ticker).PricePremium = 0.25;
            elseif strcmp(sec, 'SKT')
                app.TSystem.TradingObject.(ticker).PricePremium = 0.01;
            end
            
            if flag
                tt = max(app.TSystem.CurrentTime.(ticker)-1, 1);
                temp = app.TSystem.PositionValue.(ticker)(tt);
                app.TSystem.PositionValue.(ticker) = zeros(5000, 1); 
                app.TSystem.PositionValue.(ticker)(1) = temp;
                app.TSystem.CurrentTime.(ticker) = 2;
                app.TSystem.ExcutionValue.(ticker) = [app.TSystem.ExcutionValue.(ticker)(tt); zeros(4999, 1)];
                app.TSystem.PL.(ticker) = [app.TSystem.PL.(ticker)(tt); zeros(4999, 1)];
            else
                app.TSystem.PositionStatus.(ticker) = 0;
                app.TSystem.PositionValue.(ticker) = zeros(5000, 1); 
                app.TSystem.CurrentTime.(ticker) = 1;
                app.TSystem.ExcutionValue.(ticker) = zeros(5000, 1);
                app.TSystem.PL.(ticker) = zeros(5000, 1);
                
            end
            
            app.TSystem.MaxPosition.(ticker) = 10;
            app.TSystem.TradingShare.(ticker) = 1;
            app.TSystem.PositionChange.(ticker)  = zeros(5000, 1);
            
            initialTimer(app, ticker)
            if app.RunningFlag
                start(app.Timer.(ticker))
            end
            
        end
        
       
        % updateStrategy
        function [] = updateStrategy(app, ticker, type)
            switch type
                case 'MA'
                    Strategy.Name = 'MA';
                    Strategy.ShortWindow = 3;
                    Strategy.LongWindow = 14;
                case 'BollingerBand'
                    Strategy.Name = 'BollingerBand';
                    Strategy.n = 14;
                    Strategy.m = 2;
                case 'FastStochastic'
                    Strategy.Name = 'FastStochastic';
                    Strategy.Window = 14;
                case 'SlowStochastic'
                    Strategy.Name = 'SlowStochastic';
                    Strategy.Window = 14;
            end

            app.TSystem.TradingObject.(ticker).Strategy = Strategy;
            index = find(strcmp(app.OnRun(:, 1), ticker), 1);
            app.OnRun(index, 3) = {type};
            app.UITable.Data = app.OnRun;
        end
        
        
        % initialTimer
        function [] = initialTimer(app, ticker)
            t = timer;
            t.Name = ticker;
            t.Period = 5;
            t.StartDelay = 20;
            t.StartFcn = {@app.TradeStart, ticker};
            t.TimerFcn = {@app.Trade, ticker};
            t.ExecutionMode = 'fixedRate';
            t.TaskstoExecute = 5000;
            app.Timer.(ticker) = t;
            %t.StopFcn = @(~,thisEvent) disp(['The Trading System Stops at ',...
            %     datestr(thisEvent.Data.time, 'dd-mmm-yyyy HH:MM:SS.FFF')]);  
        end
        
        
        % TradeStart
        function [] = TradeStart(app, ~, ~, ticker)
            TObject = app.TSystem.TradingObject.(ticker);
            switch TObject.SecType
    
                case 'FUT'
                    RequestID = IBMatlab('action', 'realtime', TObject.LocalOrNot, TObject.symbol,...
                        'exchange', TObject.Exchange, 'SecType', TObject.SecType, 'QuotesNumber', app.TSystem.QuotesNumber,...
                        'QuotesBufferSize', app.TSystem.QuotesBufferSize);
                case 'STK'
                    RequestID = IBMatlab('action', 'realtime', TObject.LocalOrNot, TObject.symbol,...
                        'QuotesNumber', app.TSystem.QuotesNumber, 'QuotesBufferSize', app.TSystem.QuotesBufferSize);
            end
            if isnumeric(RequestID)
                app.TSystem.TradingObject.(ticker).RequestID = RequestID;
            else
                app.TSystem.TradingObject.(ticker).RequestID = RequestID.reqId;
            end
            

        end
    
        
        % Trade
        function [] = Trade(app, ~, ~, ticker)
            %This function finish the main part of the trading system
            %Including signal, position check, trade, update the account
            
            %  Retrieve steam bar data from TWS 
            TObject = app.TSystem.TradingObject.(ticker);
            switch TObject.SecType
                
                case 'FUT'
                    
                    Data = IBMatlab('action', 'realtime', TObject.LocalOrNot, TObject.symbol,...
                        'exchange', TObject.Exchange, 'SecType', TObject.SecType, 'QuotesNumber', TObject.RequestID);
                    data = IBMatlab('action','query', 'exchange',TObject.Exchange, ...
                        'secType',TObject.SecType, 'localSymbol',TObject.symbol);
                case 'STK'
                    
                    Data = IBMatlab('action', 'realtime', TObject.LocalOrNot, TObject.symbol, 'QuotesNumber', TObject.RequestID);
                    data = IBMatlab('action','query', 'symbol',TObject.symbol);
            end
            
            %  Signal function
            Strategy = struct2cell(TObject.Strategy);
            S = Signal(app, Data.data, Strategy{1}, Strategy{2:end});
                
            % position check and trade 
            % record the average fill price, fill price, P&L
            PriceType = TObject.PriceType;
            PricePremium = TObject.PricePremium;
            current = app.TSystem.CurrentTime.(ticker);
            
            
            %data = IBMatlab('action','query', 'exchange',TObject.exchange, ...
            %'secType',TObject.SecType, 'localSymbol',TObject.symbol);
            %data = IBMatlab('action','query', 'exchange',TObject.exchange, ...
            %TObject.SecType,'FUT', 'localSymbol',TObject.symbol)
            midPrice = (data.askPrice + data.bidPrice) / 2;
            
            switch S
            
                case 1
            
                    if (app.TSystem.PositionStatus.(ticker) < app.TSystem.MaxPosition.(ticker))
                        shares = min(app.TSystem.MaxPosition.(ticker) - app.TSystem.PositionStatus.(ticker), app.TSystem.TradingShare.(ticker));
                        if data.bidPrice~=-1
                            if strcmp(PriceType, 'LMT')
                                price = data.bidPrice+PricePremium;
                                if strcmp(app.TSystem.TradingType, 'Real')
                                    switch TObject.SecType
                                        case 'FUT'
                                            orderId = IBMatlab('action','BUY',TObject.LocalOrNot, TObject.symbol,...
                                              'exchange', TObject.Exchange, 'SecType', TObject.SecType,...
                                             'quantity', shares, 'type','LMT', 'limitPrice', price);
                                        case 'STK'
                                            orderId = IBMatlab('action','BUY','symbol',TObject.symbol,...
                                             'quantity', shares, 'type','LMT', 'limitPrice', price);
                                    end
                                end
                                 
                            elseif strcmp(PriceType, 'MKT')
                                if strcmp(app.TSystem.TradingType, 'Real')
                                    switch TObject.SecType
                                        case 'FUT'
                                            orderId = IBMatlab('action','BUY',TObject.LocalOrNot, TObject.symbol,...
                                              'exchange', TObject.Exchange, 'SecType', TObject.SecType,...
                                             'quantity', shares, 'type','MKT');
                                        case 'STK'
                                            orderId = IBMatlab('action','BUY','symbol',TObject.symbol,...
                                             'quantity', shares, 'type','MKT');
                                    end
                                    temp = IBMatlab('action','query','type','executions','OrderId',orderId);
                                    price = temp.price;
                                elseif strcmp(app.TSystem.TradingType, 'Paper')
                                    price = data.askPrice;
                                end
                                
                            end
                            
                            app.TSystem.PositionStatus.(ticker) = app.TSystem.PositionStatus.(ticker) + shares;
                            app.TSystem.PositionChange.(ticker)(current) = shares;
                            app.TSystem.PositionValue.(ticker)(current) = app.TSystem.PositionStatus.(ticker) * midPrice;
                            app.TSystem.CashValue = app.TSystem.CashValue - shares * price;
                            app.Cash = app.Cash - shares*price;
                            app.CashEditField.Value = app.Cash;
                            app.TSystem.ExcutionValue.(ticker)(current) = app.TSystem.ExcutionValue.(ticker)(max(current-1, 1)) - shares*price;
                            app.TSystem.PL.(ticker)(current) = app.TSystem.ExcutionValue.(ticker)(current) + app.TSystem.PositionValue.(ticker)(current);
                            app.TSystem.CurrentTime.(ticker) = app.TSystem.CurrentTime.(ticker) + 1;
                        end
                    else
                        app.TSystem.PositionChange.(ticker)(current) = 0;
                        app.TSystem.PositionValue.(ticker)(current) = app.TSystem.PositionStatus.(ticker) * midPrice;
                        app.TSystem.ExcutionValue.(ticker)(current) = app.TSystem.ExcutionValue.(ticker)(max(current-1, 1));
                        app.TSystem.PL.(ticker)(current) = app.TSystem.ExcutionValue.(ticker)(current) + app.TSystem.PositionValue.(ticker)(current);
                        app.TSystem.CurrentTime.(ticker) = app.TSystem.CurrentTime.(ticker) + 1;
                        
                    end
                         % Record Position and Indicator Position;
                case -1 
            
                    if (app.TSystem.PositionStatus.(ticker) > -app.TSystem.MaxPosition.(ticker))
                        shares = min(app.TSystem.MaxPosition.(ticker) + app.TSystem.PositionStatus.(ticker), app.TSystem.TradingShare.(ticker));
                        if data.askPrice~=-1
                            if strcmp(PriceType, 'LMT')
                                price = data.askPrice-PricePremium;
                                if strcmp(app.TSystem.TradingType, 'Real')
                                    switch TObject.SecType
                                        case 'FUT'
                                            orderId = IBMatlab('action','SELL',TObject.LocalOrNot, TObject.symbol,...
                                              'exchange', TObject.Exchange, 'SecType', TObject.SecType,...
                                             'quantity', shares, 'type','LMT', 'limitPrice', price);
                                        case 'STK'
                                            orderId = IBMatlab('action','SELL','symbol',TObject.symbol,...
                                             'quantity', shares, 'type','LMT', 'limitPrice', price);
                                    end
                                end
                                 
                            elseif strcmp(PriceType, 'MKT')
                                if strcmp(app.TSystem.TradingType, 'Real')
                                    switch TObject.SecType
                                        case 'FUT'
                                            orderId = IBMatlab('action','SELL',TObject.LocalOrNot, TObject.symbol,...
                                              'exchange', TObject.Exchange, 'SecType', TObject.SecType,...
                                             'quantity', shares, 'type','MKT');
                                        case 'STK'
                                            orderId = IBMatlab('action','SELL','symbol',TObject.symbol,...
                                             'quantity', shares, 'type','MKT');
                                    end
                                    temp = IBMatlab('action','query','type','executions','OrderId',orderId);
                                    price = temp.price;
                                elseif strcmp(app.TSystem.TradingType, 'Paper')
                                    price = data.bidPrice;
                                end
                            end
                            
                            app.TSystem.PositionStatus.(ticker) = app.TSystem.PositionStatus.(ticker) - shares;
                            app.TSystem.PositionChange.(ticker)(current) = -shares;
                            app.TSystem.PositionValue.(ticker)(current) = app.TSystem.PositionStatus.(ticker) * midPrice;
                            app.TSystem.ExcutionValue.(ticker)(current) = app.TSystem.ExcutionValue.(ticker)(max(current-1, 1)) + shares * price;
                            app.TSystem.CashValue = app.TSystem.CashValue + shares*price;
                            app.Cash = app.Cash + shares*price;
                            app.CashEditField.Value = app.Cash;
                            app.TSystem.PL.(ticker)(current) = app.TSystem.ExcutionValue.(ticker)(current) + app.TSystem.PositionValue.(ticker)(current);
                            app.TSystem.CurrentTime.(ticker) = app.TSystem.CurrentTime.(ticker) + 1;
                        end
                    else
                        app.TSystem.PositionChange.(ticker)(current) = 0;
                        app.TSystem.PositionValue.(ticker)(current) = app.TSystem.PositionStatus.(ticker) * midPrice;
                        app.TSystem.ExcutionValue.(ticker)(current) = app.TSystem.ExcutionValue.(ticker)(max(current-1, 1));
                        app.TSystem.PL.(ticker)(current) = app.TSystem.ExcutionValue.(ticker)(current) + app.TSystem.PositionValue.(ticker)(current);
                        app.TSystem.CurrentTime.(ticker) = app.TSystem.CurrentTime.(ticker) + 1;
                        
                    end
                
                case 0
                    app.TSystem.PositionChange.(ticker)(current) = 0;
                    app.TSystem.PositionValue.(ticker)(current) = app.TSystem.PositionStatus.(ticker) * midPrice;
                    app.TSystem.ExcutionValue.(ticker)(current) = app.TSystem.ExcutionValue.(ticker)(max(current-1, 1));
                    app.TSystem.PL.(ticker)(current) = app.TSystem.ExcutionValue.(ticker)(current) + app.TSystem.PositionValue.(ticker)(current);
                    app.TSystem.CurrentTime.(ticker) = app.TSystem.CurrentTime.(ticker) + 1;
                    
            end
            temp = {ticker, app.TSystem.PositionStatus.(ticker), app.TSystem.PositionValue.(ticker)(current), app.TSystem.PL.(ticker)(current)};
            
            index = 0;
            if size(app.Position, 1)
                index = find(strcmp(app.Position(:, 1), ticker), 1);
            end
            if isempty(index) || ~index
                app.Position = [app.Position; temp];
            end
            app.UITable2.Data = app.Position;
            if current > 1
                app.PL = app.PL + app.TSystem.PL.(ticker)(current) - app.TSystem.PL.(ticker)(current-1);
                app.PLEditField.Value = app.PL;
            else
                app.PL = app.PL + app.TSystem.PL.(ticker)(current);
                app.PLEditField.Value = app.PL;
                
            end
            if strcmp(app.Switch.Value, 'Real')
                app.RealPLEditField.Value = app.PL - app.RealPL;
            end


        end
    
        
        % DeleteSecurity
        function [] = DeleteSecurity(app, ticker)
            if app.RunningFlag
                if any(strcmp(ticker, fieldnames(app.Timer)))
                    delete(app.Timer.(ticker));
                    app.Timer = rmfield(app.Timer, ticker);
                end
            end
            
            if any(strcmp(ticker, fieldnames(app.TSystem.TradingObject)))
                app.TSystem.TradingObject = rmfield(app.TSystem.TradingObject, ticker);
                app.TSystem.MaxPosition = rmfield(app.TSystem.MaxPosition, ticker);
                app.TSystem.TradingShare = rmfield(app.TSystem.TradingShare, ticker);
                app.TSystem.PositionStatus = rmfield(app.TSystem.PositionStatus, ticker);
                app.TSystem.CurrentTime = rmfield(app.TSystem.CurrentTime, ticker);
                app.TSystem.PositionChange = rmfield(app.TSystem.PositionChange, ticker);
                app.TSystem.PositionValue = rmfield(app.TSystem.PositionValue, ticker);
                app.TSystem.ExcutionValue = rmfield(app.TSystem.ExcutionValue, ticker);
                app.TSystem.PL = rmfield(app.TSystem.PL, ticker);
                
                index = find(strcmp(app.OnRun(:, 1), ticker), 1);
                app.OnRun(index, :) = [];
                app.UITable.Data = app.OnRun;
            end

        end
    
    
        % Signal
        function [S] = Signal(app, Data, Strategy, varargin)
            % INPUT Data is the bar data(a struct object including close, open, high, low, volume, dateTime)
            % 
            % INPUT Strategy is the strategy to calculate the signal(a char object like
            % 'MA', 'FastStochastic', 'SlowStochastic', 'BollingerBand')
            
            
            switch Strategy
            
                case 'MA'
                    
                    ShortWindow = varargin{1};
                    LongWindow = varargin{2};
                    MA1 = movmean(Data.close, ShortWindow, 'Endpoints', 'discard'); 
                    MA2 = movmean(Data.close, LongWindow, 'Endpoints', 'discard');
                    MA1 = MA1((end-1):end); 
                    MA2 = MA2((end-1):end);
            
                    S = 0; 
                    if (MA1(1) < MA2(1)) && (MA1(2) > MA2(2))
                        S = 1; %up crossing
                    elseif (MA1(1) > MA2(1)) && (MA1(2) < MA2(2))
                        S = -1;
                    end
            
                case 'FastStochastic'
                    
                    Window = varargin{1};
                    Fast = Stochastic_Oscillator(app, Data, Window, 'Fast');
            
                    S = 0;
                    if (Fast(end-1) < 0.2) && (Fast(end) > 0.2)
                        S = 1;
                    elseif (Fast(end-1) > 0.8) && (Fast(end) < 0.8)
                        S = -1;
                    end
            
                case 'SlowStochastic'
                    
                    Window = varargin{1};
                    Slow = Stochastic_Oscillator(app, Data, Window);
            
                    S = 0;
                    if (Slow(end-1) < 0.2) && (Slow(end) > 0.2)
                        S = 1;
                    elseif (Slow(end-1) > 0.8) && (Slow(end) < 0.8)
                        S = -1;
                    end
            
                case 'BollingerBand'
            
                    TP = (Data.close+Data.high+Data.low)/3;
                    n = varargin{1};
                    m = varargin{2};
                    BOLU = mean(TP((end-n+1):end)) + m*std(TP((end-n+1):end));
                    BOLD = mean(TP((end-n+1):end)) - m*std(TP((end-n+1):end));
            
                    S = 0;
                    if (Data.close(end) > BOLU)
                        S = -1;
                    elseif (Data.close(end) < BOLD)
                        S = 1;
                    end
            
            end
                
        end
        
        
        % Stochastic_Oscillator
        function y = Stochastic_Oscillator(app, Data, Window, Type)
                
            % allocate the memory
            C = zeros(length(Data.close)-Window+1,1);
            H = zeros(length(C),1);
            L = zeros(length(C),1);
            
            y = zeros(length(C),1);
        
            % calculate the %K
            for i = 1:length(C)
                C(i) = Data.close(Window+i-1);
                H(i) = max(Data.high(i:Window+i-1));
                L(i) = min(Data.low(i:Window+i-1));
            
                y(i) = (C(i)-L(i))/(H(i)-L(i));
            end
            
            % calculate the 3-period moving average if Type == Fast
            if (nargin == 3) && strcmp(Type, 'Fast')
                y = movmean(y, 3, 'Endpoints', 'discard');
            end
                   
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.RunningFlag = 0;
            app.OnRun = [];
            app.Position = [];
            app.Timer = struct();
            app.TSystem.TradingType = 'Paper';
            app.TSystem.QuotesNumber = inf;
            app.TSystem.QuotesBufferSize = 5000; 
            app.TSystem.CashValue = 100000;
            app.TSystem.TradingObject = struct();
            app.PL = 0;
            app.RealPL = 0;
        end

        % Callback function: AddButton, UITable
        function AddButtonPushed(app, event)
            ticker = app.TickerEditField.Value;
            sec = app.SecTypeEditField.Value;
            strategy = app.StrategyEditField.Value;
            pricetype = app.PriceTypeEditField.Value;
            
            
            if any(strcmp(ticker, fieldnames(app.TSystem.TradingObject)))
                index = find(strcmp(app.OnRun(:, 1), ticker), 1);
                app.OnRun(index, 3) = {strategy};
                app.OnRun(index, 4) = {pricetype};
                app.UITable.Data = app.OnRun;
            else
                temp = {ticker, sec, strategy, pricetype};
                app.OnRun = [app.OnRun; temp];
                app.UITable.Data = app.OnRun;
            end
            addObject(app, ticker, sec, strategy, pricetype);
                                  
        end

        % Value changed function: PLEditField, Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            switch value
                case 'Real'
                    app.TSystem.TradingType = 'Real';
                    app.RealPL = app.PL;
                case 'Paper'
                    app.TSystem.TradingType = 'Paper';
            end
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            ticker = app.TickerEditField_2.Value;
            type = app.StrategyDropDown.Value;
            updateStrategy(app, ticker, type);
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.RunningFlag = 1;
            app.Cash = app.CashEditField.Value;
            name = fieldnames(app.Timer);
            for i = 1:length(name)
                start(app.Timer.(name{i}));
            end
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            ticker = app.TickerEditField_4.Value;

            DeleteSecurity(app, ticker);

        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            ticker = app.TickerEditField_3.Value;
            if any(strcmp(ticker, fieldnames(app.Timer))) && app.RunningFlag
                stop(app.Timer.(ticker));
            end
        end

        % Callback function: PlotButton, RealPLEditField
        function PlotButtonPushed(app, event)
            ticker = app.TickerEditField.Value;
            PLList = app.TSystem.PL.(ticker);
            plot(app.UIAxes, [1:app.TSystem.CurrentTime.(ticker)], PLList(1:app.TSystem.CurrentTime.(ticker)));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.Items = {'Paper', 'Real'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Paper';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Ticker'; 'SecType'; 'Strategy'; 'PriceType'};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @AddButtonPushed, true);
            app.TickerEditFieldLabel = uilabel(app.UIFigure);
            app.TickerEditFieldLabel.Text = 'Ticker';
            app.TickerEditField = uieditfield(app.UIFigure, 'text');
            app.TickerEditField.Value = 'ESM0';
            app.SecTypeEditFieldLabel = uilabel(app.UIFigure);
            app.SecTypeEditFieldLabel.Text = 'SecType';
            app.SecTypeEditField = uieditfield(app.UIFigure, 'text');
            app.SecTypeEditField.Value = 'FUT';
            app.StrategyEditFieldLabel = uilabel(app.UIFigure);
            app.StrategyEditFieldLabel.Text = 'Strategy';
            app.StrategyEditField = uieditfield(app.UIFigure, 'text');
            app.StrategyEditField.Value = 'MA';
            app.PriceTypeEditFieldLabel = uilabel(app.UIFigure);
            app.PriceTypeEditFieldLabel.Text = 'PriceType';
            app.PriceTypeEditField = uieditfield(app.UIFigure, 'text');
            app.PriceTypeEditField.Value = 'LMT';
            app.AddButton = uibutton(app.UIFigure, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.UITable2 = uitable(app.UIFigure);
            app.UITable2.ColumnName = {'Ticker'; 'Position'; 'MarketValue'; 'PL'};
            app.UITable2.RowName = {};
            app.DeleteButton = uibutton(app.UIFigure, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            app.PauseButton = uibutton(app.UIFigure, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Text = 'Pause';
            app.StrategyDropDownLabel = uilabel(app.UIFigure);
            app.StrategyDropDownLabel.Text = 'Strategy';
            app.StrategyDropDown = uidropdown(app.UIFigure);
            app.StrategyDropDown.Items = {'MA', 'BollingerBand', 'SlowStochastic', 'FastStochastic'};
            app.StrategyDropDown.Value = 'MA';
            app.UpdateButton = uibutton(app.UIFigure, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.CashEditFieldLabel = uilabel(app.UIFigure);
            app.CashEditFieldLabel.Text = 'Cash';
            app.CashEditField = uieditfield(app.UIFigure, 'numeric');
            app.CashEditField.LowerLimitInclusive = 'off';
            app.CashEditField.UpperLimitInclusive = 'off';
            app.CashEditField.ValueDisplayFormat = '%.0f';
            app.CashEditField.Value = 1000000;
            app.PLEditFieldLabel = uilabel(app.UIFigure);
            app.PLEditFieldLabel.Text = 'P&L';
            app.PLEditField = uieditfield(app.UIFigure, 'numeric');
            app.PLEditField.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.Text = 'Exit';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.RealPLEditFieldLabel = uilabel(app.UIFigure);
            app.RealPLEditFieldLabel.Text = 'Real P&L';
            app.RealPLEditField = uieditfield(app.UIFigure, 'numeric');
            app.RealPLEditField.ValueChangedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.AccountPositionInfoLabel = uilabel(app.UIFigure);
            app.AccountPositionInfoLabel.Text = 'Account Position Info';
            app.OnRunSecuritiesLabel = uilabel(app.UIFigure);
            app.OnRunSecuritiesLabel.Text = 'On-Run Securities';
            app.TickerEditField_2Label = uilabel(app.UIFigure);
            app.TickerEditField_2Label.Text = 'Ticker';
            app.TickerEditField_2 = uieditfield(app.UIFigure, 'text');
            app.TickerEditField_3Label = uilabel(app.UIFigure);
            app.TickerEditField_3Label.Text = 'Ticker';
            app.TickerEditField_3 = uieditfield(app.UIFigure, 'text');
            app.TickerEditField_4Label = uilabel(app.UIFigure);
            app.TickerEditField_4Label.Text = 'Ticker';
            app.TickerEditField_4 = uieditfield(app.UIFigure, 'text');
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'P&L')
            xlabel(app.UIAxes, 'time')
            ylabel(app.UIAxes, 'amount')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TradingSystem

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end